classdef A_SERIAL_STREAMER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SerialStreamerUIFigure       matlab.ui.Figure
        SelectfileButton             matlab.ui.control.Button
        OutputfileEditFieldLabel     matlab.ui.control.Label
        OutputfileEditField          matlab.ui.control.EditField
        SelectCOMPortDropDownLabel   matlab.ui.control.Label
        SelectCOMPortDropDown        matlab.ui.control.DropDown
        STARTrecordingButton         matlab.ui.control.Button
        STOPrecordingButton          matlab.ui.control.Button
        RefreshSerialportsButton     matlab.ui.control.Button
        StatusLabel                  matlab.ui.control.Label
        datastreamerLabel            matlab.ui.control.Label
        PortStatus                   matlab.ui.control.Image
        StatusMessage                matlab.ui.control.Label
        TriggerLSLnameDropDownLabel  matlab.ui.control.Label
        TriggerLSLnameDropDown       matlab.ui.control.DropDown
        RefreshLSLstreamsButton      matlab.ui.control.Button
        TriggerLabel                 matlab.ui.control.Label
        TriggerMessage               matlab.ui.control.Label
    end


    %% TODO:
    % recordStream function: put TriggerRecording into timer function,
    % executed ever 200ms or so, so ButtonCallback finishes and multiple
    % instances of this class do not block each other in a single Matlab
    % instance. --> TEST!
    
    % store Trigger time stamp & adapt HLMoutputParser! --> NOT required
    % because Aurora sends trg-number and measurement-timestamp which is
    % written to file.
    
    % serialcom object: If no data received for xx time, try to reconnect?
    
    
    properties (Constant)
        MinBytesPerLine = 8; % Minimum number of bytes that have to be availabe before trying to readline()      
        LSLlibPath = fullfile(fileparts(mfilename('fullpath')),'liblsl-Matlab');
        TimeStamp = @()datestr(now,'yyyy-mm-dd_HH-MM-SS.FFF');
    end
    
    properties %(SetAccess = private)
        PreferedLSLtrigName = 'NIRStarTriggers';
        LSLtrigLabl = 'Trigger_in';
        PreferedCOMnamePatt = '.* CH340 .*'; % regexp to match prefered COM port name
        DefaultOutputFile % Default file URI for ouput data
        WatchDogTimer; % timer object watching status and maintaining status label
        PullTrigTimer;
        reconnectCnt = 0;
    end
    
    properties %(SetAccess = private)
        SerialComObj = []; % The serial com object
        TimeLastDataStored = NaN; % time since last data chunk received
        IsRecording = false;
        OutFileLocked = false; % output file locker
        OutFile
        LSLlib = [];
        TriggerInlet = [];
    end
    
    properties (Dependent)
        LSLdisabled;
        TrgStreamCreated;
        STREAMERNAME        
    end
    
    properties (Abstract, Constant)
        DEVICENAME
    end
    properties (Abstract)
        SampleRate % rate to expect data from serial port in seconds.
        SerialPortParam
        SerialPortTerminator
    end

    
    %% ##################### PUBLIC, STATIC ###############################
    methods (Access = public, Static)
    
        function [fullnames, comNums] = getCOMports()
            if ispc
                cmd = 'powershell -command "Get-WmiObject Win32_pnpentity  -Filter """Caption LIKE ''%(COM%)%''""" | Select-Object -Property Name"';
                [s,r] = system(cmd);
                if s~=0
                    warning('SerialStreamer:getCOMports:systemCmdFailed','Failed to execute system command to retrieve com ports.')
                    [fullnames, comNums] = getCOMportsFallback();
                else
                    fullnames = regexp(r,'(?<=\n)[^\n]*\(COM\d+\)(?=\s*\n)','match');
                    comNums = regexp(fullnames,'(?<=\(COM)\d+','match','once');
                end
            else
                % retrieving full com port names for other OS no
                % implemented yet!
                [fullnames, comNums] = getCOMportsFallback();
            end
        end

        
        %% ================================================================
                
        function [fullnames, comNums] = getCOMportsFallback()
            fullnames = serialportlist("all");
            if ~iscell(fullnames)
                fullnames = cellstr(fullnames);
            end
            comNums = regexp(fullnames,'(?<=COM)\d+','match','once');
        end

        
        %% ================================================================
        
        function comName = getMatlabCOMname(sysComPort)
            comName = regexp(sysComPort,'COM\d+','match','once');
            if ~ismember(comName,serialportlist('all'))
                warning('SerialStreamer:serialPort:NameUnknown2Matlab', ...
                    'COM port ''%s'' seems to be unknown to Matlab. Creating SerialPortObject may fail!', ...
                    sysComPort);
            end            
        end

        
        %% ================================================================
        
        function sNames = getLSLstreamNames(lsllib)
            sNames = unique(cellfun(@(s)s.name(), lsl_resolve_all(lsllib,0.3) ,'UniformOutput',false));            
        end


        %% ================================================================
        
        function updateDropDown(dropdObj,newList,preferedItem)
            currentVal = dropdObj.Value;
            newValIdx = [];
            % try to maintain previous value:
            if ~isempty(currentVal)
                newValIdx = find(strcmp(newList,currentVal));
            end
            % try to set prefered value
            if isempty(newValIdx) && nargin>2
                m = regexp(newList,preferedItem,'once');
                newValIdx = find(~cellfun(@isempty,m),1);
            end
            % if new value still empty and list not empty, set to 1
            if isempty(newValIdx) && ~isempty(newList)
                newValIdx = 1;
            end
            dropdObj.Items = newList;
            if ~isempty(newValIdx)
                dropdObj.Value = newList(newValIdx);
            end
        end
        

        %% ================================================================
        
        function nbyteOut = write2file(file,tStmp,data)
            [fid,errmsg] = fopen(file,'a');
            assert(fid~=-1,'SerialStreamer:write2file:failedToOpenFile',['Failed to open file %s for writing.\n' errmsg],file);
            if ~iscell(tStmp), tStmp = {tStmp}; data = {data}; end
            assert(isequal(size(tStmp),size(data)), ...
                'SerialStreamer:write2file:incompatibleSize', ...
                'Time stamp and data must be cellstring of the same size!');
            nbyteOut = zeros(size(tStmp));
            for k = 1:numel(tStmp)
                nbyteOut(k) = fprintf(fid,'\n%s  %s',tStmp{k},data{k});
            end
            fclose(fid);
        end


    end % methods public, static ..........................................


    %% ######################## PUBLIC ####################################
    methods (Abstract)
        
        scomCallback(app,src,~)
    end
        
        %% ================================================================
    methods (Access = public)
        
        function [s,ME,po] = testSerialPort(app,name)
            comName = app.getMatlabCOMname(name);
            ME = []; po = [];
            s=0;
%             try delete(app.SerialComObj); end
            try
                po = serialport(comName,app.SerialPortParam{:});
                if nargout<3, delete(po); 
                elseif ~isempty(app.SerialPortTerminator)
                    po.configureTerminator(app.SerialPortTerminator);
                end
            catch ME
                s=1;
            end
        end
        
        %% ================================================================
        
        function [s,po]=testSerialPort_UI(app)
            portName = app.SelectCOMPortDropDown.Value;
            if isempty(portName)
                s=1; po=[];
                return;
            end
            [s,ME,po] = app.testSerialPort(portName);
            if s~=0
                warndlg({sprintf('Failed to create serial port object with COM port %s! Check/reestablish connection!',portName), ME.message}, ...
                    'Error using COM port');
            elseif nargout<2
                delete(po);
            end
        end

        
        %% ================================================================
        
        function createLSLinlet(app)
            
            sName = app.TriggerLSLnameDropDown.Value;
            try app.TriggerInlet.delete(); end
            app.TriggerInlet = [];
            if isempty(sName)
                app.TriggerMessage.Text = sprintf('%s: No trigger LSL inlet created!',datestr(now,'HH:MM:SS'));
                return;
            end

            result = lsl_resolve_byprop(app.LSLlib,'name', sName);
            if isempty(result)
                figure(app.SerialStreamerUIFigure);
                uialert(app.SerialStreamerUIFigure,{'Failed to resolve stream:',sName},'LSL error','Modal',true);
                app.TriggerMessage.Text = sprintf('%s: Failed to create trigger LSL inlet ''%s''.',datestr(now,'HH:MM:SS'),sName);
                return;
            end
            if numel(result)>1
                uialert(app.SerialStreamerUIFigure,{'Multiple streams found for name:',sName,'Using first.'},'LSL warning','Modal',true,'Icon','warning');
            end
            app.TriggerInlet = lsl_inlet(result{1});
            
            app.TriggerMessage.Text = sprintf('%s: Trigger LSL inlet ''%s'' created.',datestr(now,'HH:MM:SS'),sName);
        end        

        
        %% ================================================================
        
        function nbyteOut = saveData(app,data,tStmp,timeOut)
            % 
            nbyteOut = 0;
            if ~exist('timeOut','var') || isempty(timeOut)
                timeOut = 2;
            end
            t = tic;
            while app.OutFileLocked && toc(t)<timeOut
                pause(.1);
            end
            if app.OutFileLocked
                dSfx = datestr(now,'yymmdd-HHMMSS');
                file = sprintf('%s_%s.txt',app.OutFile,dSfx);
                unlock = false;
                figure(app.SerialStreamerUIFigure);
                uialert(app.SerialStreamerUIFigure,...
                    sprintf('waiting for file to be unlocked timed out.\nWriting to alternative file (_%s.txt)',dSfx),...
                    'Could not write to output file','Icon','warning');
                warning([app.STREAMERNAME ':saveData:UnlockFileTimeOut'], ...
                    [app.STREAMERNAME ': waiting for file to be unlocked timed out. Writing to alternative file (_%s.txt)'],...
                    dSfx);
            else
                app.OutFileLocked = true;
                file = app.OutFile;
                unlock = true;
            end
            try
                nbyteOut = app.write2file(file, tStmp, data);
            catch ME
                try fclose(file); end
                if unlock, app.OutFileLocked = false; end
                try
                    d = [tStmp(:) data(:)].';
                    d = sprintf('%s\t%s\n',d{:});
                catch
                    d = '<FAILED TO CAPTURE TIME/DATA>';
                end
                error([app.STREAMERNAME ':saveData:SavingDataFailed'], ...
                    '%s: Writing data to file ''%s'' failed. Error message:\n\t%s\nTIME/DATA:\n%s', ...
                    datestr(now,'HH:MM:SS.FFF'), file, ME.message, d);
            end
            if unlock, app.OutFileLocked = false; end
        end
        
        
        %% ================================================================
        
        function nlOut = pullTrigger(app)
            nlOut = 0;
            try
                [trg,tstmpLSL] = app.TriggerInlet.pull_chunk();
            catch ME
                app.setStatus('error');
                app.setStatusMsg('Error while pulling chunk from trigger lsl stream.');
                figure(app.SerialStreamerUIFigure);
                uialert(app.SerialStreamerUIFigure, ...
                    sprintf('There was an error while pulling chunk from trigger lsl stream...\nSee command line for further details.'), ...
                    'Error while receiving lsl data');
                rethrow(ME);
            end
            
            if ~isempty(trg)
                if size(trg,1)~=2
                    figure(app.SerialStreamerUIFigure);
                    uialert(app.SerialStreamerUIFigure, ...
                        sprintf('The lsl-trigger data has unexpected number of elements.\nCheck chosen trigger stream; name should be ''%s''.', ...
                                app.PreferedLSLtrigName), ...
                        'Unexpected trigger data');
                    app.TriggerInlet.delete();
                end
                sysTStmp = A_SERIAL_STREAMER.TimeStamp();
                nlOut = numel(tstmpLSL);
                data = cell(nlOut,1);
                tstmp = repmat({sysTStmp},size(data));
            
                for i = 1:nlOut
                    d = trg(:,i); % SAMPLES IN COLUMNS
                    data{i} = sprintf('TRIGGER  %.3f  %d  %.3f',d,tstmpLSL(i));
                end
                try
                    app.saveData(data,tstmp,2);
                catch ME
                    app.setStatus('error');
                    app.setStatusMsg('Error while saving trigger data.');
                    figure(app.SerialStreamerUIFigure);
                    uialert(app.SerialStreamerUIFigure, ...
                        sprintf('There was an unhandled error while saving trigger data.\nSee command line for further details.'), ...
                        'Error while saving data');
                    rethrow(ME);
                end
                trgStr = num2str(trg(2,:),' %d');
                app.TriggerMessage.Text = sprintf('%s: %d trigger received (%s).',datestr(now,'HH:MM:SS'),nlOut,trgStr);
            end
        end
        
    end % methods Access=public ...........................................


    %% ######################## PRIVATE ###################################
    methods (Access = private)
        
        function updateCOMportDropDown(app,newPortList)
            app.updateDropDown(app.SelectCOMPortDropDown,newPortList,app.PreferedCOMnamePatt);
        end
        
        %% ================================================================
        
        function updateLSLnamesDropDown(app,sNames)
            app.updateDropDown(app.TriggerLSLnameDropDown,sNames,app.PreferedLSLtrigName);
        end
        
        
        %% ================================================================
        
        function updateOuputEditTooltip(app)
            uri = app.OutputfileEditField.Value;
            uriShrt = regexprep(uri,'.*\\(([^\\]+\\){4}[^\\]*$)','...\\$1');
            uriShrt = regexprep(uriShrt,'\\','\n\\');
            app.OutputfileEditField.Tooltip = uriShrt;
        end
             
        
        %% ================================================================
        
        function recordStream(app)
            %
            app.IsRecording = true;
            app.setState('started');
            
            try
                app.saveData('RECORDING_started',A_SERIAL_STREAMER.TimeStamp(),2);
            catch ME
                warning([app.STREAMERNAME ':recordStream:WriteStartEvntFailed'], ...
                    'Failed to write start-recording-event to output file. Reason:\n%s',ME.message);
            end
            configureCallback(app.SerialComObj,'terminator',@(src,evt)scomCallback(app,src,evt));
            
            app.PullTrigTimer.start();

        end
        
        
        %% ================================================================
        
        function stopRecording(app)
            app.IsRecording = false;
            configureCallback(app.SerialComObj,'off');
            app.PullTrigTimer.stop();
            app.SerialComObj.delete();
            app.setState('stopped');
            try
                app.saveData('RECORDING_stopped',A_SERIAL_STREAMER.TimeStamp(),2);
            catch ME
                warning([app.STREAMERNAME ':recordStream:WriteStoppEvntFailed'], ...
                    'Failed to write stopp-recording-event to output file. Reason:\n%s',ME.message);
            end
        end
        
        
        %% ================================================================
        
        function setState(app,state)
            state = validatestring(state,{'started','stopped'});
            % UI to be turned ON if started:
            objOn.started =  {app.STOPrecordingButton};
            % UI to be turned OFF if started:
            objOff.started = {app.STARTrecordingButton, ...
                              app.SelectCOMPortDropDown, ...
                              app.OutputfileEditField, ...
                              app.SelectfileButton, ...
                              app.RefreshSerialportsButton, ...
                              app.TriggerLSLnameDropDown, ...
                              app.RefreshLSLstreamsButton
                             };
            % complement:
            objOn.stopped = objOff.started;
            objOff.stopped = objOn.started;
            
            % turn UI on:
            for iObj = 1:numel(objOn.(state))
                objOn.(state){iObj}.Enable = 'on';
            end          
            % turn UI off:
            for iObj = 1:numel(objOff.(state))
                objOff.(state){iObj}.Enable = 'off';
            end
            
            app.setStatus(state);
        end
        
        
        %% ================================================================
        
        function setStatus(app,stat)
            switch lower(stat)
                case 'stopped'
                    app.setStatusMsg('Idle',[.5 .5 .5]);
                case 'started'
                    app.setStatusMsg('Listening...',[0.93,0.69,0.13]);
                case 'receiving'
                    app.setStatusMsg('Receiving data.',[0.39,0.83,0.07]);
                case 'error'
                    app.setStatusMsg('E R R O R',[0.85,0.33,0.10]);
                otherwise
                    warning([app.STREAMERNAME ':setStatus:unknownStatus'],'status string ''%s'' not implemented!',stat)
            end
        end
        
        
        %% ================================================================
        
        function setStatusMsg(app,strng,color)
            app.StatusMessage.Text = strng;
            if nargin>2
                app.StatusMessage.FontColor = color;
            end
        end
        

        %% ================================================================
        
        function pullTrigTimerFcn(app,th,event)
            if app.TrgStreamCreated
                nlOut = app.pullTrigger();
            end
        end
        

        %% ================================================================
        
        function pullTrigErrorFcn(app,th,event)
            app.setStatus('error');
            app.TriggerMessage.Text = 'Error in TriggerTimer. Stopped receiving trigger.';
            warning(event.Data.message);
        end
        

        %% ================================================================
        
        function pullTrigStopFcn(app,th,event)
            
        end
        

        %% ================================================================
        
        function watchDogTimerFcn(app,th,event)
            if ~isempty(app.SerialComObj) && isvalid(app.SerialComObj) && ...
                   ~strcmpi(app.SerialComObj.BytesAvailableFcnMode, 'off')
                if isnan(app.TimeLastDataStored)
                    app.setStatusMsg('Listening... (no samples received)',[0.93,0.69,0.13]);
                elseif now - app.TimeLastDataStored > app.SampleRate/86400 * 1.1
                    tLastSam = (now-app.TimeLastDataStored)*86400;
                    app.setStatusMsg(sprintf('Listening... (last sample received %.0fs ago)',tLastSam),[0.93,0.69,0.13]);
                    if app.reconnectCnt==0
                        app.STOPrecordingButtonPushed(0);
                        app.STARTrecordingButtonPushed(0);
                    end
                    app.reconnectCnt = app.reconnectCnt+1;
                    if app.reconnectCnt>4, app.reconnectCnt = 0; end
                else 
                    app.setStatus('receiving');
                    app.reconnectCnt = 0;
                end
                
            else
                app.setStatus('stopped');
            end
        end
        
        
        %% ================================================================
        
        function watchDogErrorFcn(app,th,event)
            app.setStatus('error');
            app.setStatusMsg('Error in WatchDog, Unknown status.')
            warning(event.Data.message);
        end
        
        
        %% ================================================================
        
        function watchDogStopFcn(app,th,event)
            
        end

        
        %% ================================================================
        
        function flushLSLtrgStream(app)
            if app.TrgStreamCreated
                app.TriggerInlet.pull_chunk();
            end
        end
        
    end
    
    
    %% ####################### GET METHODS ################################
    
    methods
        function v = get.STREAMERNAME(app)
            v = sprintf('%sstreamer',app.DEVICENAME);
        end
        
        function v = get.LSLdisabled(app)
            v = isempty(app.LSLlib);
        end
        
        function v = get.TrgStreamCreated(app)
            v = isa(app.TriggerInlet,'lsl_inlet') && isvalid(app.TriggerInlet);
        end
    end
    
    
    %% ####################### CALLBACKS ##################################

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.datastreamerLabel.Text = [app.DEVICENAME app.datastreamerLabel.Text];
            
            app.SerialStreamerUIFigure.Name = [app.DEVICENAME app.SerialStreamerUIFigure.Name];
            
            appPth = fileparts(mfilename('fullpath'));
            app.DefaultOutputFile = fullfile(appPth, ...
                sprintf('%s_output_%s.txt',app.DEVICENAME,datestr(now,'yyyy-mm-dd')));
            
            app.OutputfileEditField.Value = app.DefaultOutputFile;
            app.updateOuputEditTooltip();
            app.updateCOMportDropDown(app.getCOMports);
            
            app.RefreshLSLstreamsButton.Icon = fullfile(appPth,'refresh.png');
            app.RefreshSerialportsButton.Icon = fullfile(appPth,'refresh.png');
            addpath(appPth);
            
            addpath(genpath(app.LSLlibPath));
            if isempty(which('lsl_loadlib'))
                uialert(app.SerialStreamerUIFigure,{'LSL library not found in',app.LSLlibPath},'LSL error','Modal',true);
            else
                app.setStatusMsg('Loading lsl lib... ',[0.93,0.69,0.13]);
                drawnow();
                app.LSLlib = lsl_loadlib();
            end
            if app.LSLdisabled
            else
                try
                    app.updateLSLnamesDropDown(app.getLSLstreamNames(app.LSLlib));
                    app.createLSLinlet();
                end
            end
            
            app.WatchDogTimer = timer('Name',       'watchDogTimer', ...
                                      'ExecutionMode','fixedRate', ...
                                      'ErrorFcn',   @app.watchDogErrorFcn, ...
                                      'StopFcn',    @app.watchDogStopFcn, ...
                                      'TimerFcn',   @app.watchDogTimerFcn);
            app.WatchDogTimer.start();
            
            app.PullTrigTimer = timer('Name',       'pullTrigTimer', ...
                                      'ExecutionMode','fixedRate', ...
                                      'ErrorFcn',   @app.pullTrigErrorFcn, ...
                                      'StopFcn',    @app.pullTrigStopFcn, ...
                                      'TimerFcn',   @app.pullTrigTimerFcn, ...
                                      'Period',     .5, ...
                                      'BusyMode',   'drop');

        end

        % Button pushed function: RefreshSerialportsButton
        function RefreshSerialportsButtonPushed(app, event)
            app.updateCOMportDropDown(app.getCOMports);
            app.testSerialPort_UI();
        end

        % Button pushed function: RefreshLSLstreamsButton
        function RefreshLSLstreamsButtonPushed(app, event)
            if app.LSLdisabled
                uialert(app.SerialStreamerUIFigure,{'LSL library not loaded. Check path:',app.LSLlibPath},'LSL error','Modal',true);
            else
                try
                    app.updateLSLnamesDropDown(app.getLSLstreamNames(app.LSLlib));
                catch ME
                    uialert(app.SerialStreamerUIFigure,{'Failed to fetch LSL streams.',ME.message},'LSL error','Modal',true);
                    rethrow(ME);
                end
                
            end
        end

        % Value changed function: SelectCOMPortDropDown
        function SelectCOMPortDropDownValueChanged(app, event)
            app.testSerialPort_UI();
        end

        % Value changed function: TriggerLSLnameDropDown
        function TriggerLSLnameDropDownValueChanged(app, event)
            app.createLSLinlet();
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            [f,p] = uiputfile(app.OutputfileEditField.Value,'Select Output File');
            if f==0, return; end
            if ~exist(p,'dir'), mkdir(p); end
            app.OutputfileEditField.Value = fullfile(p,f);
            app.updateOuputEditTooltip();
        end

        % Button pushed function: STARTrecordingButton
        function STARTrecordingButtonPushed(app, event)
            % check com port
            [s,scomObj] = app.testSerialPort_UI();
            if s~=0, return; end
            
            % check output file
            if isempty(app.OutputfileEditField.Value)
                app.SelectfileButtonPushed([]);
            end
            if isempty(app.OutputfileEditField.Value)
                uialert(app.SerialStreamerUIFigure, 'Please specify an output file first!', 'Output file required');
                delete(scomObj);
                return;
            end
            outfile = app.OutputfileEditField.Value;
            [fid,errmsg] = fopen(outfile,'a');
            if fid==-1
                uialert(app.SerialStreamerUIFigure, {'Cannot open file', outfile 'for writing! Check permissions.' errmsg},...
                    'Failed to open file');
                delete(scomObj);
                return;
            end
            fclose(fid);
            
            % start sampling
            app.TimeLastDataStored = NaN;
            app.SerialComObj = scomObj;
            app.OutFile = outfile;
            app.createLSLinlet();
            try
                app.recordStream();
            catch ME
                uialert(app.SerialStreamerUIFigure, ...
                    {'There was an unhandled error while recording the stream.', 'See command line for more details.'},...
                    'ERROR RECORDING STREAM');
                try app.stopRecording(); end
                app.setStatus('error');
                rethrow(ME);
            end
%             app.flushLSLtrgStream();

        end

        % Button pushed function: STOPrecordingButton
        function STOPrecordingButtonPushed(app, event)
            app.stopRecording();
        end

        % Close request function: SerialStreamerUIFigure
        function SerialStreamerUIFigureCloseRequest(app, event)
            try
                if ~isempty(app.SerialComObj) && isvalid(app.SerialComObj) && ...
                   ~strcmpi(app.SerialComObj.BytesAvailableFcnMode, 'off')
                
                    r = questdlg('Sampling seems to be running. Sure to quit?','Confirm to quit','yes','No','No');
                    if ~strcmpi(r,'yes')
                        return;
                    end
                    
                end
            end
            try app.SerialComObj.delete(); end
            try app.WatchDogTimer.stop(); end
            try app.WatchDogTimer.delete(); end
            
%             try rmpath(fileparts(mfilename("fullpath"))); end
%             try rmpath(genpath(app.LSLlibPath)); end
            
            delete(app)
            
        end

        % Value changed function: OutputfileEditField
        function OutputfileEditFieldValueChanged(app, event)
            app.updateOuputEditTooltip();            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SerialStreamerUIFigure = uifigure('Visible', 'off');
            app.SerialStreamerUIFigure.Name = 'streamer';
            app.SerialStreamerUIFigure.CloseRequestFcn = createCallbackFcn(app, @SerialStreamerUIFigureCloseRequest, true);
            app.SelectfileButton = uibutton(app.SerialStreamerUIFigure, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.OutputfileEditFieldLabel = uilabel(app.SerialStreamerUIFigure);
            app.OutputfileEditFieldLabel.Text = 'Output file:';
            app.OutputfileEditField = uieditfield(app.SerialStreamerUIFigure, 'text');
            app.OutputfileEditField.ValueChangedFcn = createCallbackFcn(app, @OutputfileEditFieldValueChanged, true);
            app.SelectCOMPortDropDownLabel = uilabel(app.SerialStreamerUIFigure);
            app.SelectCOMPortDropDownLabel.Text = 'Select COM Port:';
            app.SelectCOMPortDropDown = uidropdown(app.SerialStreamerUIFigure);
            app.SelectCOMPortDropDown.Items = {};
            app.SelectCOMPortDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectCOMPortDropDownValueChanged, true);
            app.SelectCOMPortDropDown.Tooltip = {'Choose COM port to which the streaming device is connected to. See Device Manager (Geräte Manager), name should be ''USB-SERIAL CH340'''};
            app.SelectCOMPortDropDown.Value = {};
            app.STARTrecordingButton = uibutton(app.SerialStreamerUIFigure, 'push');
            app.STARTrecordingButton.ButtonPushedFcn = createCallbackFcn(app, @STARTrecordingButtonPushed, true);
            app.STARTrecordingButton.Text = 'START recording';
            app.STOPrecordingButton = uibutton(app.SerialStreamerUIFigure, 'push');
            app.STOPrecordingButton.ButtonPushedFcn = createCallbackFcn(app, @STOPrecordingButtonPushed, true);
            app.STOPrecordingButton.Enable = 'off';
            app.STOPrecordingButton.Text = 'STOP recording';
            app.RefreshSerialportsButton = uibutton(app.SerialStreamerUIFigure, 'push');
            app.RefreshSerialportsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshSerialportsButtonPushed, true);
            app.RefreshSerialportsButton.Tooltip = {'Refresh list of COM ports'};
            app.RefreshSerialportsButton.Text = '';
            app.StatusLabel = uilabel(app.SerialStreamerUIFigure);
            app.StatusLabel.Text = 'Status:';
            app.datastreamerLabel = uilabel(app.SerialStreamerUIFigure);
            app.datastreamerLabel.Text = ' data streamer';
            app.PortStatus = uiimage(app.SerialStreamerUIFigure);
            app.PortStatus.Visible = 'off';
            app.StatusMessage = uilabel(app.SerialStreamerUIFigure);
            app.StatusMessage.Text = ' Idle';
            app.TriggerLSLnameDropDownLabel = uilabel(app.SerialStreamerUIFigure);
            app.TriggerLSLnameDropDownLabel.Text = 'Trigger LSL name';
            app.TriggerLSLnameDropDown = uidropdown(app.SerialStreamerUIFigure);
            app.TriggerLSLnameDropDown.Items = {};
            app.TriggerLSLnameDropDown.ValueChangedFcn = createCallbackFcn(app, @TriggerLSLnameDropDownValueChanged, true);
            app.TriggerLSLnameDropDown.Tooltip = {'Select Trigger input LSL stream. Should be ''NIRStarTriggers'' for NIRx Aurora'};
            app.TriggerLSLnameDropDown.Value = {};
            app.RefreshLSLstreamsButton = uibutton(app.SerialStreamerUIFigure, 'push');
            app.RefreshLSLstreamsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshLSLstreamsButtonPushed, true);
            app.RefreshLSLstreamsButton.Tooltip = {'Refresh list of availabel LSL streams'};
            app.RefreshLSLstreamsButton.Text = '';
            app.TriggerLabel = uilabel(app.SerialStreamerUIFigure);
            app.TriggerLabel.Text = 'Trigger:';
            app.TriggerMessage = uilabel(app.SerialStreamerUIFigure);
            app.TriggerMessage.Text = '';
            % Show the figure after all components are created
            app.SerialStreamerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A_SERIAL_STREAMER

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SerialStreamerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SerialStreamerUIFigure)
        end
    end
end